classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        AmplitudeSliderLabel  matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
        OpenMeshDirButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        cads;
        class_names;
        cls_ind;
        cad_index;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000 1000];
            
        end

        % Button pushed function: OpenMeshDirButton
        function OpenMeshDirButtonPushed(app, event)
            mesh_dir = uigetdir;
            fprintf('Loading mesh files ......\n');
            fprintf('This will take some time. Please wait until you see the message "done".\n');
            obj = load(sprintf('%s\\cads.mat', mesh_dir));
            cads = obj.cads;
            class_names = obj.class_names;
            app.cads = cads;
            app.class_names = class_names;
            app.cls_ind = find(strcmp(class_names, 'drones') == 1);
            app.cad_index = 1;
            faces = app.cads{app.cls_ind}(app.cad_index).faces;
            if isfield(app.cads{app.cls_ind},'face4') == 1
                face4 = app.cads{app.cls_ind}(app.cad_index).face4;
            end
            vertices = app.cads{app.cls_ind}(app.cad_index).vertices;
            set(app.UIFigure,'CurrentAxes',app.UIAxes);
            trimesh(faces,vertices(:,1),vertices(:,2),vertices(:,3), 'EdgeColor','b');
            disp('Done');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.OpenMeshDirButton = uibutton(app.UIFigure, 'push');
            app.OpenMeshDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMeshDirButtonPushed, true);
            app.OpenMeshDirButton.Text = 'Open Mesh Dir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end